classdef FileCompressionProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageCompressionDecompressionUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        CompressionMenu                matlab.ui.container.Menu
        DecompressionMenu              matlab.ui.container.Menu
        ExitMenu                       matlab.ui.container.Menu
        VideoMenu                      matlab.ui.container.Menu
        CompressionMenu_2              matlab.ui.container.Menu
        DecompressionMenu_2            matlab.ui.container.Menu
        ExitMenu_2                     matlab.ui.container.Menu
        SelectFileButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        CompressionRatioLabel          matlab.ui.control.Label
        CompressionRatioEditField      matlab.ui.control.EditField
        LblSelectFile                  matlab.ui.control.Label
        CompressFileButton             matlab.ui.control.Button
        DecompressFileButton           matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        CompressionSizeEditFieldLabel  matlab.ui.control.Label
        CompressionSizeEditField       matlab.ui.control.EditField
        OriginalSizeEditFieldLabel     matlab.ui.control.Label
        OriginalSizeEditField          matlab.ui.control.EditField
        ClearAllButton                 matlab.ui.control.Button
        MatrixSizeDropDownLabel        matlab.ui.control.Label
        MatrixSizeDropDown             matlab.ui.control.DropDown
        CoefficientValueDropDownLabel  matlab.ui.control.Label
        CoefficientValueDropDown       matlab.ui.control.DropDown
        FilenameLabel                  matlab.ui.control.Label
        UnCompressedSizeLabel          matlab.ui.control.Label
        UnCompressedSizeEditField      matlab.ui.control.EditField
    end
    
    methods (Access = private)
    
        % Menu selected function: CompressionMenu
        function CompressionMenuSelected(app, event)
            app.LblSelectFile.Visible=true;
            app.LblSelectFile.Enable=true;
            app.SelectFileButton.Enable=true;
            %print('Enter file for compression');
        end
        
        % Button pushed function: SelectFileButton
        function SelectFileButtonPushed(app, event)
            global fname;
            UIAxes.Title.string='Input Image';
            UIAxes2.Title.string='Output Image';
            [filename pathname]=uigetfile({'*.png'},'File Selector');
            fname=fullfile(pathname,filename);
            imshow(fname,'Parent',app.UIAxes);
            msgbox ('done1');    
            app.DecompressFileButton.Enable = true;
            app.CompressFileButton.Enable=true;
            app.LblSelectFile.Text=fname;
            app.LblSelectFile.Visible=false;
            app.FilenameLabel.Text=filename;
            app.FilenameLabel.Visible=false;
            
        end

        % Callback function: CompressFileButton, 
        % CompressionSizeEditField
        function CompressFileButtonPushed(app, event)
            app.LblSelectFile.Visible=false;
            app.CompressionRatioEditField.Enable=true;
            UnCompressedSizeEditField.Visble=false;
            fname=app.LblSelectFile.Text;
            filename = app.FilenameLabel.Text;
            msgbox('done');
            mat_size=app.MatrixSizeDropDown.Value
            coeff_val=app.CoefficientValueDropDown.Value
            i_blocksize = str2double(mat_size);
            i_coeff = str2double(coeff_val);
            msgbox('done2');
            im = imread(fname);
            outpath = strcat('C:\Users\nirup\Desktop\Shalini Study Reading University\Image processing\2018_ImageCompression\Demo\Compressed_',string(i_coeff),'_',filename);
            T = dctmtx(i_blocksize);
            
            % defining co-efficients for block size 8[5]
            if i_blocksize == 8
                if i_coeff == 1
                    mask = [1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif i_coeff == 4 % defining co-efficients for block size 4
                    mask = [1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 8
                    mask = [1   1   1   1   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 10
                    mask = [1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 12
                    mask = [1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 16
                    mask = [1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 20
                    mask = [1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 24
                    mask = [1   1   1   1   1   1   1   1
                            1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 24
                    mask = [1   1   1   1   1   1   1   1
                            1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                end
            elseif i_blocksize == 4
                if i_coeff == 1
                   mask =  [1   0   0   0 
                            0   0   0   0 
                            0   0   0   0 
                            0   0   0   0 ];
                elseif i_coeff == 4
                   mask =  [1   1   0   0 
                            1   0   0   0 
                            1   0   0   0 
                            0   0   0   0 ];
                elseif  i_coeff == 8
                    mask = [1   1   1   1 
                            1   1   0   0 
                            1   0   0   0 
                            1   0   0   0 ];
                elseif  i_coeff == 10
                    mask = [1   1   1   1
                            1   1   1   0
                            1   1   0   0
                            1   0   0   0];
                elseif  i_coeff == 12
                    mask = [1   1   1   1
                            1   1   1   0
                            1   1   1   0
                            1   1   0   0];
                end        
            end
            
            if (size(im,3)>1)    
        
                % get individual planes [5]
                R_im = im2double(im(:,:,1));
                G_im = im2double(im(:,:,2));
                B_im = im2double(im(:,:,3));
        
                % matrix of 8X8 [5]
                dct = @(block_struct) T * block_struct.data * T';
        
                % divide image in 8x* block and applying DCT
                R = blockproc(R_im,[i_blocksize i_blocksize],dct);
                B = blockproc(B_im,[i_blocksize i_blocksize],dct);
                G = blockproc(G_im,[i_blocksize i_blocksize],dct);
                
                % Applying mask[5]
                R_mask = blockproc(R,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                G_mask = blockproc(G,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                B_mask = blockproc(B,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                
                % inverse DCT[5]
                funinvdct = @(block_struct) T' * block_struct.data * T;
                R_image = blockproc(R_mask,[i_blocksize i_blocksize],funinvdct);
                B_image = blockproc(G_mask,[i_blocksize i_blocksize],funinvdct);
                G_image = blockproc(B_mask,[i_blocksize i_blocksize],funinvdct);
                
                % cat
                comp_image(:,:,:)=cat(3,R_image, B_image, G_image);
           else
                % get individual planes
                R_im = im2double(im);
                
                % DCT
                dct = @(block_struct) T * block_struct.data * T';
        
                % divide image in 8x* block and applying DCT
                R = blockproc(R_im,[i_blocksize i_blocksize],dct);
                
                R_mask = blockproc(R,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                % inverse
                funinvdct = @(block_struct) T' * block_struct.data * T;
                % divide image in 4x4 block and applying DCT
                comp_image = blockproc(R_mask,[i_blocksize i_blocksize],funinvdct);
            end
            
            % write file
            app.LblSelectFile.Text = outpath;
            imwrite(comp_image,outpath);
            imshow(comp_image,'Parent',app.UIAxes2);
            
            %  input file size   
            fileinfo = dir(fname);
            SIZE = fileinfo.bytes;
            imSize = SIZE/1024;
            
            %  output file size 
            fileinfo = dir(string(outpath));
            SIZE = fileinfo.bytes;
            outSize = SIZE/1024;
              
            % Compression Ratio & PSNR 
            [peaksnr, snr] = psnr(double(imread(outpath)), double(im));
            compressionratio = (outSize/imSize)* 100;
            compressionratio_per = 100 - ((outSize/imSize)* 100);
            app.CompressionRatioEditField.Value = string(compressionratio_per);
            app.CompressionSizeEditField.Value = string(outSize);
            app.OriginalSizeEditField.Value = string(imSize);

        end
        
        % Button pushed function: DecompressFileButton
        function DecompressFileButtonPushed(app, event)
            app.LblSelectFile.Visible=false;
            app.CompressionRatioEditField.Enable=false;
            fname=app.LblSelectFile.Text;            
            filename = app.FilenameLabel.Text;
            msgbox('done');
            mat_size=app.MatrixSizeDropDown.Value
            coeff_val=app.CoefficientValueDropDown.Value
            i_blocksize = str2double(mat_size);
            i_coeff = str2double(coeff_val);
            msgbox('done2');
            im = imread(fname);
            outpath = strcat('C:\Users\nirup\Desktop\Shalini Study Reading University\Image processing\2018_ImageCompression\Demo\Decompressed_',string(i_coeff),'_',filename);
            T = dctmtx(i_blocksize);
            if i_blocksize == 8
                if i_coeff == 1
                    mask = [1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif i_coeff == 4
                    mask = [1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 8
                    mask = [1   1   1   1   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 10
                    mask = [1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 12
                    mask = [1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 16
                    mask = [1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 20
                    mask = [1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 24
                    mask = [1   1   1   1   1   1   1   1
                            1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                elseif  i_coeff == 24
                    mask = [1   1   1   1   1   1   1   1
                            1   1   1   1   1   1   0   0
                            1   1   1   1   0   0   0   0
                            1   1   1   0   0   0   0   0
                            1   1   0   0   0   0   0   0
                            1   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0
                            0   0   0   0   0   0   0   0];
                end
            elseif i_blocksize == 4
                if i_coeff == 1
                   mask =  [1   0   0   0 
                            0   0   0   0 
                            0   0   0   0 
                            0   0   0   0 ];
                elseif i_coeff == 4
                   mask =  [1   1   0   0 
                            1   0   0   0 
                            1   0   0   0 
                            0   0   0   0 ];
                elseif  i_coeff == 8
                    mask = [1   1   1   1 
                            1   1   0   0 
                            1   0   0   0 
                            1   0   0   0 ];
                elseif  i_coeff == 10
                    mask = [1   1   1   1
                            1   1   1   0
                            1   1   0   0
                            1   0   0   0];
                elseif  i_coeff == 12
                    mask = [1   1   1   1
                            1   1   1   0
                            1   1   1   0
                            1   1   0   0];
                end        
            end
            
            if (size(im,3)>1)    
        
                % get individual planes
                R_im = im2double(im(:,:,1));
                G_im = im2double(im(:,:,2));
                B_im = im2double(im(:,:,3));
        
                % matrix of 8X8
                dct = @(block_struct) T' * block_struct.data * T;
        
                % divide image in 8x* block and applying DCT
                R = blockproc(R_im,[i_blocksize i_blocksize],dct);
                B = blockproc(B_im,[i_blocksize i_blocksize],dct);
                G = blockproc(G_im,[i_blocksize i_blocksize],dct);
                
                % Applying mask
                R_mask = blockproc(R,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                G_mask = blockproc(G,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                B_mask = blockproc(B,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                
                % inverse DCT
                funinvdct = @(block_struct) T * block_struct.data * T';
                R_image = blockproc(R_mask,[i_blocksize i_blocksize],funinvdct);
                B_image = blockproc(G_mask,[i_blocksize i_blocksize],funinvdct);
                G_image = blockproc(B_mask,[i_blocksize i_blocksize],funinvdct);
                
                % cat
                comp_image(:,:,:)=cat(3,R_image, B_image, G_image);
            else
                % get individual planes
                R_im = im2double(im);
                
                % DCT
                dct = @(block_struct) T' * block_struct.data * T;
        
                % divide image in 8x* block and applying DCT
                R = blockproc(R_im,[i_blocksize i_blocksize],dct);
                
                R_mask = blockproc(R,[i_blocksize i_blocksize],@(block_struct) mask .* block_struct.data );
                % inverse
                funinvdct = @(block_struct) T * block_struct.data * T';
                % divide image in 4x4 block and applying DCT
                comp_image = blockproc(R_mask,[i_blocksize i_blocksize],funinvdct);
            end
            
            % write file
            outpath = 'C:\Users\nirup\Desktop\Shalini Study Reading University\Imageprocessing\2018_ImageCompression\Demo\Uncompressed.png'
            imwrite(comp_image,outpath);
            imshow(comp_image,'Parent',app.UIAxes2);
            
            %  input file size   
            fileinfo = dir(fname);
            SIZE = fileinfo.bytes;
            imSize = SIZE/1024;
            
            %  output file size 
            fileinfo = dir(string(outpath));
            SIZE = fileinfo.bytes;
            outSize = SIZE/1024;
              
            % Compression Ratios
            app.CompressionSizeEditField.Value = string(imSize);
            app.UnCompressedSizeEditField.Value=string(outSize);

              
        end

         % Menu selected function: CompressionMenu_2
        function CompressionMenu_2Selected(app, event)
            app.OriginalSizeEditField.Enable=true;
            app.OriginalSizeEditField.Visible=true;
            app.CompressionRatioEditField.Enable=true;
            app.CompressionRatioEditField.visible=true;
            app.UnCompressedSizeEditField.Enable=false;
            app.UnCompressedSizeEditField.Visible=true;
        end
        % Menu selected function: DecompressionMenu
        function DecompressionMenuSelected(app, event)
            app.OriginalSizeEditField.Enable=false;
            app.CompressionRatioEditField.Enable=false;
            app.CompressFileButton.Enable = false;
            app.SelectFileButton.Enable=true;
        end
        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.UIAxes.cla;
            app.UIAxes.reset;
            app.UIAxes2.cla;
            app.UIAxes2.reset;
            app.CompressionRatioEditField.Value="";
            app.OriginalSizeEditField.Value="";
            app.CompressionSizeEditField.Value="";
            app.LblSelectFile.Text="";
                    
        end
        % Menu selected function: DecompressionMenu_2
        function DecompressionMenu_2Selected(app, event)
            app.OriginalSizeEditField.Enable=false;
            app.CompressionRatioEditField.Enable=false;
            app.UnCompressedSizeEditField.Enable=true;
            app.UnCompressedSizeEditField.Visible=true;
        end
    end

    
    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageCompressionDecompressionUIFigure = uifigure;
            app.ImageCompressionDecompressionUIFigure.Name = 'Image Compression Decompression';
            app.FileMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.FileMenu.Text = 'File';
            app.CompressionMenu = uimenu(app.FileMenu);
            app.CompressionMenu.MenuSelectedFcn = createCallbackFcn(app, @CompressionMenuSelected, true);
            app.CompressionMenu.Tooltip = {'Open file for compression'};
            app.CompressionMenu.Text = 'Compression';
            app.DecompressionMenu = uimenu(app.FileMenu);
            app.DecompressionMenu.MenuSelectedFcn = createCallbackFcn(app, @DecompressionMenuSelected, true);
            app.DecompressionMenu.Text = 'Decompression';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.Text = 'Exit';
            app.VideoMenu = uimenu(app.ImageCompressionDecompressionUIFigure);
            app.VideoMenu.Text = 'Video';
            app.CompressionMenu_2 = uimenu(app.VideoMenu);
            app.CompressionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @CompressionMenu_2Selected, true);
            app.CompressionMenu_2.Text = 'Compression';
            app.DecompressionMenu_2 = uimenu(app.VideoMenu);
            app.DecompressionMenu_2.MenuSelectedFcn = createCallbackFcn(app, @DecompressionMenu_2Selected, true);
            app.DecompressionMenu_2.Text = 'Decompression';
            app.ExitMenu_2 = uimenu(app.VideoMenu);
            app.ExitMenu_2.Text = 'Exit';
            app.SelectFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.SelectFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFileButtonPushed, true);
            app.SelectFileButton.Enable = 'off';
            app.SelectFileButton.Text = 'Select File';
            app.UIAxes = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes, 'Input Image')
            app.CompressionRatioLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionRatioLabel.Text = 'Compression Ratio(%)';
            app.CompressionRatioEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.CompressionRatioEditField.Editable = 'off';
            app.LblSelectFile = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.LblSelectFile.Enable = 'off';
            app.LblSelectFile.Visible = 'off';
            app.LblSelectFile.Text = 'Select file';
            app.CompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.CompressFileButton.ButtonPushedFcn = createCallbackFcn(app, @CompressFileButtonPushed, true);
            app.CompressFileButton.Enable = 'off';
            app.CompressFileButton.Tooltip = {'Click to compress file'};
            app.CompressFileButton.Text = 'Compress File';
            app.DecompressFileButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.DecompressFileButton.ButtonPushedFcn = createCallbackFcn(app, @DecompressFileButtonPushed, true);
            app.DecompressFileButton.Enable = 'off';
            app.DecompressFileButton.Tooltip = {'Click to decompress file'};
            app.DecompressFileButton.Text = 'Decompress File';
            app.UIAxes2 = uiaxes(app.ImageCompressionDecompressionUIFigure);
            title(app.UIAxes2, 'Output  Image')
            app.CompressionSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CompressionSizeEditFieldLabel.Text = 'Compression Size';
            app.CompressionSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.CompressionSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CompressFileButtonPushed, true);
            app.CompressionSizeEditField.Editable = 'off';
            app.OriginalSizeEditFieldLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.OriginalSizeEditFieldLabel.Text = 'Original Size';
            app.OriginalSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.OriginalSizeEditField.Editable = 'off';
            app.ClearAllButton = uibutton(app.ImageCompressionDecompressionUIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.MatrixSizeDropDownLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.MatrixSizeDropDownLabel.Text = 'Matrix Size';
            app.MatrixSizeDropDown = uidropdown(app.ImageCompressionDecompressionUIFigure);
            app.MatrixSizeDropDown.Items = {'4', '8'};
            app.MatrixSizeDropDown.Value = '4';
            app.CoefficientValueDropDownLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.CoefficientValueDropDownLabel.Text = 'Co-efficient Value';
            app.CoefficientValueDropDown = uidropdown(app.ImageCompressionDecompressionUIFigure);
            app.CoefficientValueDropDown.Items = {'1', '4', '8', '10', '12'};
            app.CoefficientValueDropDown.Value = '1';
            app.FilenameLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.FilenameLabel.Visible = 'off';
            app.FilenameLabel.Text = 'Filename';
            app.UnCompressedSizeLabel = uilabel(app.ImageCompressionDecompressionUIFigure);
            app.UnCompressedSizeLabel.Text = 'UnCompressed Size';
            app.UnCompressedSizeEditField = uieditfield(app.ImageCompressionDecompressionUIFigure, 'text');
            app.UnCompressedSizeEditField.Editable = 'off';
        end
        
    end

methods (Access = public)

        % Construct app
        function app = FileCompressionProject

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageCompressionDecompressionUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageCompressionDecompressionUIFigure)
        end
    end
end